classdef TFestimate_App_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        ChooseTFListBoxLabel            matlab.ui.control.Label
        ChooseTFListBox                 matlab.ui.control.ListBox
        InputDataTypeButtonGroup        matlab.ui.container.ButtonGroup
        FreqReal_TFImag_TFButton        matlab.ui.control.RadioButton
        FreqComplex_TFButton            matlab.ui.control.RadioButton
        FRDModelButton                  matlab.ui.control.RadioButton
        SmoothButton                    matlab.ui.control.StateButton
        ExtremeSmoothButton             matlab.ui.control.StateButton
        Result_folder_nameEditFieldLabel  matlab.ui.control.Label
        Result_folder_nameEditField     matlab.ui.control.EditField
        InitMethodLabel                 matlab.ui.control.Label
        InitMethodEditField             matlab.ui.control.EditField
        SearchMethodLabel               matlab.ui.control.Label
        SearchMethodEditField           matlab.ui.control.EditField
        MinPolesEditFieldLabel          matlab.ui.control.Label
        MinPolesEditField               matlab.ui.control.NumericEditField
        MaxPolesLabel                   matlab.ui.control.Label
        MaxPolesEditField               matlab.ui.control.NumericEditField
        Optionsivsvfgpmfn4sidautoLabel  matlab.ui.control.Label
        OptionsgngnalmlsqnonlingradautoLabel  matlab.ui.control.Label
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusField                     matlab.ui.control.EditField
        TfestButton                     matlab.ui.control.Button
        MaxIterEditFieldLabel           matlab.ui.control.Label
        MaxIterEditField                matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        ExFmin1Fmax1Fmin2Fmax2Fmin3Fmax3Label  matlab.ui.control.Label
        FrequencyWeightingFilterButtonGroup  matlab.ui.container.ButtonGroup
        PassbandsHzButton               matlab.ui.control.RadioButton
        InverseButton                   matlab.ui.control.RadioButton
        InverseSquareRootButton         matlab.ui.control.RadioButton
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        SmoothButton_2                  matlab.ui.control.Button
        Slider                          matlab.ui.control.Slider
        Slider_2                        matlab.ui.control.Slider
        Slider_3                        matlab.ui.control.Slider
        Slider_4                        matlab.ui.control.Slider
        XlimFilterButton                matlab.ui.control.Button
        ExtremeSmoothButton_2           matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        SavePlotButton                  matlab.ui.control.Button
        SavePlotButton_2                matlab.ui.control.Button
        FigNameEditFieldLabel           matlab.ui.control.Label
        FigNameEditField                matlab.ui.control.EditField
        RescueButton                    matlab.ui.control.Button
        RationalfitButton               matlab.ui.control.Button
        AddnewfileButton                matlab.ui.control.Button
        PlotsTab                        matlab.ui.container.Tab
        UIAxes2_37                      matlab.ui.control.UIAxes
        UIAxes2_38                      matlab.ui.control.UIAxes
        UIAxes2_39                      matlab.ui.control.UIAxes
        UIAxes2_40                      matlab.ui.control.UIAxes
        UIAxes2_41                      matlab.ui.control.UIAxes
        UIAxes2_42                      matlab.ui.control.UIAxes
        UIAxes2_43                      matlab.ui.control.UIAxes
        UIAxes2_44                      matlab.ui.control.UIAxes
        UIAxes2_45                      matlab.ui.control.UIAxes
        UIAxes2_46                      matlab.ui.control.UIAxes
        UIAxes2_47                      matlab.ui.control.UIAxes
        UIAxes2_48                      matlab.ui.control.UIAxes
        StatusLamp_5                    matlab.ui.control.Lamp
        StatusField_5                   matlab.ui.control.EditField
        RescueButton_2                  matlab.ui.control.Button
        FilterTab                       matlab.ui.container.Tab
        SOSFilterFOTONTextAreaLabel     matlab.ui.control.Label
        SOSFilterFOTONTextArea          matlab.ui.control.TextArea
        ResultsforNEditFieldLabel       matlab.ui.control.Label
        ResultsforNEditField            matlab.ui.control.NumericEditField
        ResultsforNEditFieldLabel_2     matlab.ui.control.Label
        StatusLampLabel_2               matlab.ui.control.Label
        StatusLamp_6                    matlab.ui.control.Lamp
        StatusField_6                   matlab.ui.control.EditField
        GetNewTFButton                  matlab.ui.control.Button
        ZeroTextAreaLabel               matlab.ui.control.Label
        ZeroTextArea                    matlab.ui.control.TextArea
        PoleTextAreaLabel               matlab.ui.control.Label
        PoleTextArea                    matlab.ui.control.TextArea
        GainTextAreaLabel               matlab.ui.control.Label
        GainTextArea                    matlab.ui.control.TextArea
        RevertButton                    matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        TextArea                        matlab.ui.control.TextArea
        GetCommandLineOutputButton      matlab.ui.control.Button
        UpButton                        matlab.ui.control.Button
        DownButton                      matlab.ui.control.Button
        RescueButton_3                  matlab.ui.control.Button
        Slider_5                        matlab.ui.control.Slider
        Slider_6                        matlab.ui.control.Slider
        XlimFilterButton_2              matlab.ui.control.Button
        SavePlotButton_3                matlab.ui.control.Button
        FigNameEditField_2Label         matlab.ui.control.Label
        FigNameEditField_2              matlab.ui.control.EditField
        InfoTab                         matlab.ui.container.Tab
        TextArea2                       matlab.ui.control.TextArea
        TFESTIMATELabel                 matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function  updateListFunc(app)
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;
            
            Current_Dir = pwd;
            csvwrite('Current_Dir.txt',Current_Dir);
            copyfile('Current_Dir.txt','./measured_TFs/');
            
            
        end

        % Button pushed function: AddnewfileButton
        function AddnewfileButtonPushed(app, event)
            [FileName, PathName, FilterIndex] = uigetfile( ...
                {  '*.*','All Files'}, ...
                'Pick a file', ...
                'MultiSelect', 'on');
            
            if FilterIndex ~= 0
                NewFilePath  = sprintf('%s%s',PathName,FileName);
                copyfile(NewFilePath,'./measured_TFs/');
                display('New file copied to ./measured_TFs/');
            end
            
            
            Files = dir('./measured_TFs');
            File_list = cell(numel(Files)-2,1);
            count = 1;
            for i = 3:numel(Files)
                File_list{count} = Files(i).name;
                count = count+1;
            end
            app.ChooseTFListBox.Items = File_list;
        end

        % Callback function
        function ChooseTFListBoxValueChanged(app, event)
            addpath('measured_TFs');
            
            TF_file = app.ChooseTFListBox.Value;
            
            if strcmp(TF_file(end-3:end),'.txt')
                app.FRDModelButton.Value = 0;
            end


            if (app.FreqReal_TFImag_TFButton.Value) == 1
                x  = load(char(TF_file));
                y = x(:,2)+1i*x(:,3);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FreqComplex_TFButton.Value) == 1
                x  = load(char(TF_file));
                y  = x(:,2);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FRDModelButton.Value) == 1
                sys = load(['./measured_TFs/',char(TF_file)]);
                sys = sys.sys;
            end


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            if app.SmoothButton.Value == 1 && app.ExtremeSmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag  = smooth(TF_mag,'sgolay',1);
                TF_phs  = smooth(TF_phs,'sgolay',1);
                color_val =   [0.8500  ,  0.3250 ,   0.0980];
            elseif app.ExtremeSmoothButton.Value == 1 && app.SmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag =  csaps(TF_freq,TF_mag,0.99999,TF_freq);
                TF_phs =  csaps(TF_freq,TF_phs,0.99999,TF_freq);
                color_val = [    0.9290  ,  0.6940 ,   0.1250];
            else
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                color_val = [  0  ,  0.4470  ,  0.7410];
            end
            
            TF_TF= TF_mag.*exp(1i*TF_phs);


            sys  = frd(TF_TF,TF_W);
            save('./measured_TFs/sys.mat','sys');
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';
            
            %[TF_mag,TF_phs] = bode(sys_inp_smoothed,BO); TF_mag = squeeze(TF_mag); TF_phs = squeeze(TF_phs);
            
            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            title(app.UIAxes,sprintf('%s',char(TF_file)),'interpreter','none');
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')
            title(app.UIAxes_2,'');
            
            
            app.updateListFunc;
            
            Va1_1 = app.FreqReal_TFImag_TFButton.Value ;
            Va1_2 = app.FreqComplex_TFButton.Value ;
            Val_3 = app.FRDModelButton.Value ;
            
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            
            if  app.ResultsforNEditField.Value >= app.MinPolesEditField.Value + 1 
            
            app.ResultsforNEditField.Value = app.ResultsforNEditField.Value - 1 ;
            
            Nvalue = app.ResultsforNEditField.Value;
            
            N = app.ResultsforNEditField.Value;
            
            
            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',Nvalue));
            app.SOSFilterFOTONTextArea.Value = cwText;
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            format shortEng
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            format shortEng
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';
            
            
            %zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            z = app.ZeroTextArea.Value;
            %z  = zText{:};
            if strcmp(z(end),{''})
                z(end) = '';
            end
            %z(cellfun(@isnan,z)) = [];
            %z(cellfun(@isinf,z)) = [];
            z = cellfun(@str2double,z);
            
            %pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            p = app.PoleTextArea.Value;
            %p  = pText{:};
            if strcmp(p(end),{''})
                p(end) = '';
            end
            %p(cellfun(@isnan,p)) = [];
            %p(cellfun(@isinf,p)) = [];
            p = cellfun(@str2double,p);
            
            %kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            k = app.GainTextArea.Value;
            %k  = kText{:};
            if strcmp(k(end),{''})
                k(end) = '';
            end
            %k(cellfun(@isnan,k)) = [];
            %k(cellfun(@isinf,k)) = [];
            k = cellfun(@str2double,k);
            
            disp(z)
            z(isnan(z))=[];
            p(isnan(p))=[];
            k(isnan(k))=[];
            
            G = zpk(z,p,k);
            save('zpkFilter.mat','G');
            
            [G_mag,G_phs, G_W]  = bode(G,'BO');
            G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
            G_freq             = G_W;


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');


            cd(Current_Dir);
            
            end
            
        end

        % Callback function
        function ExtremeSmoothButtonValueChanged(app, event)
            addpath('measured_TFs');
            TF_file = app.ChooseTFListBox.Value;
            
            app.SmoothButton.Value = 0;
            
            
            if (app.FreqReal_TFImag_TFButton.Value) == 1
                x  = load(char(TF_file));
                y = x(:,2)+1i*x(:,3);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FreqComplex_TFButton.Value) == 1
                x  = load(char(TF_file));
                y  = x(:,2);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FRDModelButton.Value) == 1
                sys = load(char(TF_file));
                sys = sys.sys;
            end


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            
            if app.SmoothButton.Value == 1 && app.ExtremeSmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag  = smooth(TF_mag,'sgolay',1);
                TF_phs  = smooth(TF_phs,'sgolay',1);
                color_val =   [0.8500  ,  0.3250 ,   0.0980];
            elseif app.ExtremeSmoothButton.Value == 1 && app.SmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag =  csaps(TF_freq,TF_mag,0.99999,TF_freq);
                TF_phs =  csaps(TF_freq,TF_phs,0.99999,TF_freq);
                color_val = [    0.9290  ,  0.6940 ,   0.1250];
            else
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                color_val = [  0  ,  0.4470  ,  0.7410];
            end
            
            TF_TF= TF_mag.*exp(1i*TF_phs);
            
            
            sys = frd(TF_TF,TF_W);
            save('./measured_TFs/sys.mat','sys');
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';


            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')
            
            
            app.updateListFunc;
        end

        % Button pushed function: ExtremeSmoothButton_2
        function ExtremeSmoothButton_2Pushed(app, event)
            FMinVal = app.Slider.Value;
            FMaxVal = app.Slider_2.Value;
            
            addpath('measured_TFs');
            
            
            TF_file = app.ChooseTFListBox.Value;


            if (app.FreqReal_TFImag_TFButton.Value) == 1
                x  = load(char(TF_file));
                y = x(:,2)+1i*x(:,3);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FreqComplex_TFButton.Value) == 1
                x  = load(char(TF_file));
                y  = x(:,2);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FRDModelButton.Value) == 1
                sys = load(char(TF_file));
                sys = sys.sys;
            end


            app.ExtremeSmoothButton.Value = 0;
            app.SmoothButton.Value = 0;
            
            
            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            
            [~,FMinID] = min(abs(TF_freq - FMinVal));
            [~,FMaxID] = min(abs(FMaxVal - TF_freq));


            %             if app.SmoothButton.Value == 1 && app.ExtremeSmoothButton.Value == 0
            disp(1)
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            TF_mag(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_mag(FMinID:FMaxID),app.EditField_3.Value,TF_freq(FMinID:FMaxID));
            TF_phs(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_phs(FMinID:FMaxID),app.EditField_3.Value,TF_freq(FMinID:FMaxID));
            color_val =   [0.8500  ,  0.3250 ,   0.0980];
            
            %             elseif app.ExtremeSmoothButton.Value == 1 && app.SmoothButton.Value == 0
            %                 disp(2)
            %                 TF_mag  = abs(TF_resp);
            %                 TF_phs  = angle(TF_resp);
            %                 TF_mag(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_mag(FMinID:FMaxID),0.99999,TF_freq(FMinID:FMaxID));
            %                 TF_phs(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_phs(FMinID:FMaxID),0.99999,TF_freq(FMinID:FMaxID));
            %                 color_val = [    0.9290  ,  0.6940 ,   0.1250];
            %             else
            %                 disp(3)
            %                 TF_mag  = abs(TF_resp);
            %                 TF_phs  = angle(TF_resp);
            %                 color_val = [  0  ,  0.4470  ,  0.7410];
            %             end
           
            TF_TF= TF_mag.*exp(1i*TF_phs);
            
            
            sys  = frd(TF_TF,TF_W);
            save('./measured_TFs/sys.mat','sys');
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';


            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')


            app.updateListFunc;
            
        end

        % Button pushed function: GetCommandLineOutputButton
        function GetCommandLineOutputButtonPushed(app, event)
            jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
            jCmdWin = jDesktop.getClient('Command Window');
            jTextArea = jCmdWin.getComponent(0).getViewport.getView;
            cwText = char(jTextArea.getText);
            app.TextArea.Value = cwText;
            
        end

        % Button pushed function: GetNewTFButton
        function GetNewTFButtonPushed(app, event)
            
            
            N = app.ResultsforNEditField.Value;
            Current_Dir = pwd;
            
            %zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            z = app.ZeroTextArea.Value;
            %z  = zText{:};
            if strcmp(z(end),{''})
                z(end) = '';
            end
            %z(cellfun(@isnan,z)) = [];
            %z(cellfun(@isinf,z)) = [];
            z = cellfun(@str2double,z);
            
            %pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            p = app.PoleTextArea.Value;
            %p  = pText{:};
            if strcmp(p(end),{''})
                p(end) = '';
            end
            %p(cellfun(@isnan,p)) = [];
            %p(cellfun(@isinf,p)) = [];
            p = cellfun(@str2double,p);
            
            %kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            k = app.GainTextArea.Value;
            %k  = kText{:};
            if strcmp(k(end),{''})
                k(end) = '';
            end
            %k(cellfun(@isnan,k)) = [];
            %k(cellfun(@isinf,k)) = [];
            k = cellfun(@str2double,k);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Get SOS
            clc
            filename = sprintf('SOS_NPoles_%d_modified',N);
            % quack3andahalf_modified(G,4096,filename);
            %quack3andahalf(G,4096);
            
            try
                
                quack3andahalf_modified(zpk(cplxpair(z,0.001),cplxpair(p,0.001),real(k)),4096,filename);
                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
            catch
                disp('Attempt 1')
                % % Additional tweaks to make SOS Foton Filters without errors (may be inaccurate)
                try
                    quack3andahalf_modified(zpk(cplxpair(z,0.01),cplxpair(p,0.01),real(k)),4096,filename);
                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                catch
                    disp('Attempt 2')
                    try
                        quack3andahalf_modified(zpk(cplxpair(z,0.1),cplxpair(p,0.1),real(k)),4096,filename);
                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                    catch
                        disp('Attempt 3')
                        try
                            quack3andahalf_modified(zpk(cplxpair(z,0.3),cplxpair(p,0.3),real(k)),4096,filename);
                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                        catch
                            disp('Attempt 4')
                            try
                                quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                            catch
                                disp('Attempt 5')
                                try
                                    quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                catch
                                    disp(['Could not generate FOTON SOS Filters with N=',num2str(N),' Poles']);
                                end
                            end
                        end
                    end
                end
            end


            cd(Current_Dir)
            
            % Save Fig
            %saveas(Fig3,sprintf('%s/tfResult.png',Result_folder_name));
            %saveas(Fig3,sprintf('%s/tfResult.fig',Result_folder_name));
            
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            movefile(sprintf('%s.txt',filename),  sprintf('%s',filepath));
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',N));
            app.SOSFilterFOTONTextArea.Value = cwText;
            cd(Current_Dir);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';
            
            G = zpk(z,p,k);
            
            [G_mag,G_phs, G_W]  = bode(G,'BO');
            G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
            G_freq             = G_W;


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');


        end

        % Size changed function: InputTab
        function InputTabSizeChanged(app, event)
            position = app.InputTab.Position;
            
        end

        % Button pushed function: RationalfitButton
        function RationalfitButtonPushed(app, event)
     
            app.StatusField.Value = 'Busy';
            app.StatusField_5.Value = 'Busy';
            app.StatusField_6.Value = 'Busy';
            app.StatusLamp.Color = 'red';
            app.StatusLamp_5.Color = 'red';
            app.StatusLamp_6.Color = 'red';
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            % Main Result folder
            if ~exist('tf_ResultFolder','dir') == 7
                mkdir('tf_ResultFolder')
            end
            
            Result_folder_fullPath = sprintf('./tf_ResultFolder/%s',char(app.Result_folder_nameEditField.Value));
            
            Current_dir = pwd;
            try
                movefile('SOS_full_path.txt', './junk')
                movefile('Result_folder_fullPath.txt','./junk');
            catch
            end


            % prevent existing Result file overwrite
            if exist(Result_folder_fullPath,'dir')==7
                Result_folder_fullPath = sprintf('%s_%d',Result_folder_fullPath,randi(1e4));
            end
            
            mkdir(Result_folder_fullPath);
            cd(Result_folder_fullPath);
            Result_folder_name = pwd;
            SOS_folder_name = 'SOS_Results';
            mkdir(SOS_folder_name);
            cd(SOS_folder_name)
            SOS_full_path = pwd;
            
            cd(Current_dir)
            diary('SOS_full_path.txt');
            disp(SOS_full_path)
            diary('off')
            
            diary('Result_folder_fullPath.txt');
            disp(Result_folder_fullPath)
            diary('off')


            IDX = 1;
            Count = 1;


            for N = app.MinPolesEditField.Value:app.MaxPolesEditField.Value
                
                disp(['Estimating TF using ',num2str(N),' Poles...'])
                
                try
                    
                    
                    BO = bodeoptions;
                    BO.PhaseWrapping = 'on';
                    BO.FreqUnits = 'Hz';
                    
                    
                    FW = load('FilterWeights.mat'); FW = FW.FilterWeights;
                    FW = FW'/2/pi;
                    
                    
                   sys = load('./measured_TFs/sys.mat');
                   sys = sys.sys;
            
            
                    [TF_resp,TF_W]       = frdata(sys,'v');
                    TF_freq              = TF_W/2/pi;
                    TF_mag               = abs(TF_resp);
                    TF_phs               = angle(TF_resp);
                    
                    
                    %%% Weighting Filter
                    if app.PassbandsHzButton.Value == 1
                        
                        
                        if numel(FW)     == 12
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6); FW(7),FW(8);FW(9),FW(10); FW(11),FW(12);];                            
                        elseif numel(FW) == 10
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6);FW(7),FW(8); FW(9),FW(10);];
                        elseif numel(FW)   == 8
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6); FW(7),FW(8);];
                        elseif numel(FW) == 6
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6);];
                        elseif numel(FW) == 4
                            WeightFilter =  [FW(1),FW(2);FW(3),FW(4);];
                        elseif numel(FW) == 2
                            WeightFilter = [FW(1),FW(2);];
                        else
                            WeightFilter = [0.01,30;]*2*pi;
                        end
                        
                        Weight = get_weight(TF_freq,WeightFilter);
                        
                    elseif app.InverseButton.Value == 1
                        Weight = 1/abs(TF_mag);
                        
                    elseif app.InverseSquareRootButton.Value == 1
                        Weight = 1/sqrt(abs(TF_mag));
                        
                    end


            NPoles = N;


            TF_TF= TF_mag.*exp(1i*TF_phs);
            TF_TF_orig = TF_TF;
            
            
            % Fit a rational function [Vector Fitting]
            % https://in.mathworks.com/help/rf/ug/rationalfit.html


            % fit = rationalfit(TF_freq,TF_TF,'Weight',Weight);
            fit = rationalfit(TF_freq,TF_TF,'NPoles',NPoles,'Weight',Weight);
            % fit = rationalfit(TF_freq,TF_TF,'NPoles',NPoles);
            
            %customresp = freqresp(fit,TF_freq);
            
            [b,a] = residue(fit.C,fit.A,fit.D);
            sys_mod = tf(real(b),a);
            [z,p,k] = zpkdata(sys_mod,'v');


                    [G_mag,G_phs, G_W]  = bode(sys_mod,'BO');
                    G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
                    G_freq             = G_W;


                    [TF_resp,TF_W]  = frdata(sys,'v');
                    TF_freq             = TF_W;
                    TF_mag  = abs(TF_resp);
                    TF_phs  = angle(TF_resp);
                    
                    
                    if Count == 1
                        
                        loglog(app.UIAxes2_37,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_37,'on');
                        hold(app.UIAxes2_37,'on');
                        loglog(app.UIAxes2_37,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_37,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_37,'Frequency (Hz)')
                        ylabel(app.UIAxes2_37,'Mag')
                        legend(app.UIAxes2_37,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_37,'off');
                        
                        
                        semilogx(app.UIAxes2_38,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_38,'on');
                        hold(app.UIAxes2_38,'on');
                        semilogx(app.UIAxes2_38,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_38,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_38,'Frequency (Hz)')
                        ylabel(app.UIAxes2_38,'Phase (deg)')
                        hold(app.UIAxes2_38,'off');
                        
                        %figure(111)
                        %bode(sys,BO)
                        %hold on
                        %bode(G,BO)
                        %[z,p,k] = zpkdata(G);
                        %H = zpk(z,p,k);
                        %bode(H,BO)


                    elseif Count == 2
                        
                        loglog(app.UIAxes2_39,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_39,'on');
                        hold(app.UIAxes2_39,'on');
                        loglog(app.UIAxes2_39,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_39,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_39,'Frequency (Hz)')
                        ylabel(app.UIAxes2_39,'Mag')
                        legend(app.UIAxes2_39,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_39,'off');
                        
                        
                        semilogx(app.UIAxes2_40,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_40,'on');
                        hold(app.UIAxes2_40,'on');
                        semilogx(app.UIAxes2_40,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_40,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_40,'Frequency (Hz)')
                        ylabel(app.UIAxes2_40,'Phase (deg)')
                        hold(app.UIAxes2_40,'off');
                        
                    elseif Count == 3
                        
                        loglog(app.UIAxes2_41,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_41,'on');
                        hold(app.UIAxes2_41,'on');
                        loglog(app.UIAxes2_41,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_41,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_41,'Frequency (Hz)')
                        ylabel(app.UIAxes2_41,'Mag')
                        legend(app.UIAxes2_41,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_41,'off');
                        
                        
                        semilogx(app.UIAxes2_42,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_42,'on');
                        hold(app.UIAxes2_42,'on');
                        semilogx(app.UIAxes2_42,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_42,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_42,'Frequency (Hz)')
                        ylabel(app.UIAxes2_42,'Phase (deg)')
                        hold(app.UIAxes2_42,'off');
                        
                    elseif Count == 4
                        
                        loglog(app.UIAxes2_43,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_43,'on');
                        hold(app.UIAxes2_43,'on');
                        loglog(app.UIAxes2_43,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_43,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_43,'Frequency (Hz)')
                        ylabel(app.UIAxes2_43,'Mag')
                        legend(app.UIAxes2_43,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_43,'off');
                        
                        
                        semilogx(app.UIAxes2_44,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_44,'on');
                        hold(app.UIAxes2_44,'on');
                        semilogx(app.UIAxes2_44,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_44,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_44,'Frequency (Hz)')
                        ylabel(app.UIAxes2_44,'Phase (deg)')
                        hold(app.UIAxes2_44,'off');
                        
                    elseif Count == 5
                        
                        loglog(app.UIAxes2_45,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_45,'on');
                        hold(app.UIAxes2_45,'on');
                        loglog(app.UIAxes2_45,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_45,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_45,'Frequency (Hz)')
                        ylabel(app.UIAxes2_45,'Mag')
                        legend(app.UIAxes2_45,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_45,'off');
                        
                        
                        semilogx(app.UIAxes2_46,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_46,'on');
                        hold(app.UIAxes2_46,'on');
                        semilogx(app.UIAxes2_46,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_46,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_46,'Frequency (Hz)')
                        ylabel(app.UIAxes2_46,'Phase (deg)')
                        hold(app.UIAxes2_46,'off');
                        
                        
                    elseif Count == 6
                        
                        loglog(app.UIAxes2_47,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_47,'on');
                        hold(app.UIAxes2_47,'on');
                        loglog(app.UIAxes2_47,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_47,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_47,'Frequency (Hz)')
                        ylabel(app.UIAxes2_47,'Mag')
                        legend(app.UIAxes2_47,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_47,'off');
                        
                        
                        semilogx(app.UIAxes2_48,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_48,'on');
                        hold(app.UIAxes2_48,'on');
                        semilogx(app.UIAxes2_48,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_48,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_48,'Frequency (Hz)')
                        ylabel(app.UIAxes2_48,'Phase (deg)')
                        hold(app.UIAxes2_48,'off');
                        
                        
                    end
                    
                    Count = Count + 1;


                    tfDATA(IDX).NPoles = N;
                    tfDATA(IDX).zpkDATA.z = z;
                    tfDATA(IDX).zpkDATA.p = p;
                    tfDATA(IDX).zpkDATA.k = k;
                    
                    filename = sprintf('SOS_NPoles_%d',N);
                    % quack3andahalf_modified(G,4096,filename);
                    %quack3andahalf(G,4096);
                    
                    try
                        
                        quack3andahalf_modified(zpk(cplxpair(z,0.001),cplxpair(p,0.001),real(k)),4096,filename);
                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                    catch
                        disp('Attempt 1')
                        % % Additional tweaks to make SOS Foton Filters without errors (may be inaccurate)
                        try
                            quack3andahalf_modified(zpk(cplxpair(z,0.01),cplxpair(p,0.01),real(k)),4096,filename);
                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                        catch
                            disp('Attempt 2')
                            try
                                quack3andahalf_modified(zpk(cplxpair(z,0.1),cplxpair(p,0.1),real(k)),4096,filename);
                                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                            catch
                                disp('Attempt 3')
                                try
                                    quack3andahalf_modified(zpk(cplxpair(z,0.3),cplxpair(p,0.3),real(k)),4096,filename);
                                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                catch
                                    disp('Attempt 4')
                                    try
                                        quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                    catch
                                        disp('Attempt 5')
                                        try
                                            quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                        catch
                                            disp(['Could not generate FOTON SOS Filters with N=',num2str(N),' Poles']);
                                        end
                                    end
                                end
                            end
                        end
                    end
                    movefile(sprintf('%s.txt',filename),  sprintf('%s',SOS_full_path));
                    
                    
                    IDX = IDX+1;
                    
                catch exception
                    msgTxt = getReport(exception)
                    IDX = IDX+1;
                    continue
                end
                
            end
            
            cd(Current_dir)
            
            % Save Fig
            %saveas(Fig3,sprintf('%s/tfResult.png',Result_folder_name));
            %saveas(Fig3,sprintf('%s/tfResult.fig',Result_folder_name));
            
            % Save TF Fit Dat
            save(sprintf('%s/tfDATA.mat',Result_folder_name),'tfDATA');
            
            disp(['Results saved to ',Result_folder_name])
            
            
            app.StatusField.Value = char(['Results saved to: ', Result_folder_name]);
            app.StatusField_5.Value = char(['Results saved to: ', Result_folder_name]);
            app.StatusField_6.Value = char(['Results saved to: ', Result_folder_name]);
            
            app.StatusLamp.Color='green';
            app.StatusLamp_5.Color='green';
            app.StatusLamp_6.Color='green';
            
            
            value = app.MinPolesEditField.Value;
            app.ResultsforNEditField.Value = value;
            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',value));
            app.SOSFilterFOTONTextArea.Value = cwText;
            cd(Current_Dir);
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            
            Nvalue = app.MaxPolesEditField.Value;
            app.ResultsforNEditField.Value = Nvalue;
            
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            G = zpk(zDATA,pDATA,kDATA);
            save('zpkFilter.mat','G');
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');
            
            
            cd(Current_Dir)


        end

        % Button pushed function: RescueButton
        function RescueButtonPushed(app, event)
            
            %% Change to Default Path
            filepath = strtrim(fileread('Current_Dir.txt'));
            filepath(filepath==',') = [];
            cd(filepath)
            
            fclose('all');
            
            %% Default Settings
            app.StatusField.Value = '';
            app.StatusField_5.Value = '';
            app.StatusField_6.Value = '';
            app.FreqReal_TFImag_TFButton.Value = 1;
            app.FreqComplex_TFButton.Value = 0;
            app.FRDModelButton.Value = 0;
            app.SmoothButton.Value = 1;
            app.ExtremeSmoothButton.Value = 0;
            app.EditField.Value = char(sprintf('%d,%d',0.01,5));
            app.MinPolesEditField.Value = 6;
            app.MaxPolesEditField.Value = 11;
            app.Result_folder_nameEditField.Value = char('TF_Results');
            app.InitMethodEditField.Value = char('svf');
            app.SearchMethodEditField.Value = char('lsqnonlin');
            app.MaxIterEditField.Value = 100;
            app.StatusLamp.Color = 'green';
            app.StatusLamp_5.Color = 'green';
            app.StatusLamp_6.Color = 'green';


        end

        % Button pushed function: RescueButton_2
        function RescueButton_2Pushed(app, event)
                        
            %% Change to Default Path
            filepath = strtrim(fileread('Current_Dir.txt'));
            filepath(filepath==',') = [];
            cd(filepath)
            
            fclose('all');
            
            %% Default Settings
            app.StatusField.Value = '';
            app.StatusField_5.Value = '';
            app.StatusField_6.Value = '';
            app.FreqReal_TFImag_TFButton.Value = 1;
            app.FreqComplex_TFButton.Value = 0;
            app.FRDModelButton.Value = 0;
            app.SmoothButton.Value = 1;
            app.ExtremeSmoothButton.Value = 0;
            app.EditField.Value = char(sprintf('%d,%d',0.01,5));
            app.MinPolesEditField.Value = 6;
            app.MaxPolesEditField.Value = 11;
            app.Result_folder_nameEditField.Value = char('TF_Results');
            app.InitMethodEditField.Value = char('svf');
            app.SearchMethodEditField.Value = char('lsqnonlin');
            app.MaxIterEditField.Value = 100;
            app.StatusLamp.Color = 'green';
            app.StatusLamp_5.Color = 'green';
            app.StatusLamp_6.Color = 'green';
        end

        % Button pushed function: RescueButton_3
        function RescueButton_3Pushed(app, event)
                        
            %% Change to Default Path
            filepath = strtrim(fileread('Current_Dir.txt'));
            filepath(filepath==',') = [];
            cd(filepath)
            
            fclose('all');
            
            %% Default Settings
            app.StatusField.Value = '';
            app.StatusField_5.Value = '';
            app.StatusField_6.Value = '';
            app.FreqReal_TFImag_TFButton.Value = 1;
            app.FreqComplex_TFButton.Value = 0;
            app.FRDModelButton.Value = 0;
            app.SmoothButton.Value = 1;
            app.ExtremeSmoothButton.Value = 0;
            app.EditField.Value = char(sprintf('%d,%d',0.01,5));
            app.MinPolesEditField.Value = 6;
            app.MaxPolesEditField.Value = 11;
            app.Result_folder_nameEditField.Value = char('TF_Results');
            app.InitMethodEditField.Value = char('svf');
            app.SearchMethodEditField.Value = char('lsqnonlin');
            app.MaxIterEditField.Value = 100;
            app.StatusLamp.Color = 'green';
            app.StatusLamp_5.Color = 'green';
            app.StatusLamp_6.Color = 'green';
        end

        % Value changed function: ResultsforNEditField
        function ResultsforNEditFieldValueChanged(app, event)
            Nvalue = app.ResultsforNEditField.Value;
            N = app.ResultsforNEditField.Value;
            
            
            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',Nvalue));
            app.SOSFilterFOTONTextArea.Value = cwText;
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';
            
            
            %zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            z = app.ZeroTextArea.Value;
            %z  = zText{:};
            if strcmp(z(end),{''})
                z(end) = '';
            end
            %z(cellfun(@isnan,z)) = [];
            %z(cellfun(@isinf,z)) = [];
            z = cellfun(@str2double,z);
            
            %pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            p = app.PoleTextArea.Value;
            %p  = pText{:};
            if strcmp(p(end),{''})
                p(end) = '';
            end
            %p(cellfun(@isnan,p)) = [];
            %p(cellfun(@isinf,p)) = [];
            p = cellfun(@str2double,p);
            
            %kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            k = app.GainTextArea.Value;
            %k  = kText{:};
            if strcmp(k(end),{''})
                k(end) = '';
            end
            %k(cellfun(@isnan,k)) = [];
            %k(cellfun(@isinf,k)) = [];
            k = cellfun(@str2double,k);
            
            disp(z)
            z(isnan(z))=[];
            p(isnan(p))=[];
            k(isnan(k))=[];
            
            G = zpk(z,p,k);
            
            [G_mag,G_phs, G_W]  = bode(G,'BO');
            G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
            G_freq             = G_W;


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');


            cd(Current_Dir);
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            Nvalue = app.ResultsforNEditField.Value;
            N = app.ResultsforNEditField.Value;


            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',Nvalue));
            app.SOSFilterFOTONTextArea.Value = cwText;
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Get ZPK
            
            %zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            z = app.ZeroTextArea.Value;
            %z  = zText{:};
            if strcmp(z(end),{''})
                z(end) = '';
            end
            z = cellfun(@str2double,z);
            
            %pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            p = app.PoleTextArea.Value;
            %p  = pText{:};
            if strcmp(p(end),{''})
                p(end) = '';
            end
            p = cellfun(@str2double,p);
            
            %kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            k = app.GainTextArea.Value;
            %k  = kText{:};
            if strcmp(k(end),{''})
                k(end) = '';
            end
            k = cellfun(@str2double,k);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Get SOS
            clc
            filename = sprintf('SOS_NPoles_%d',N);
            % quack3andahalf_modified(G,4096,filename);
            %quack3andahalf(G,4096);
            
            try
                
                quack3andahalf_modified(zpk(cplxpair(z,0.001),cplxpair(p,0.001),real(k)),4096,filename);
                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
            catch
                disp('Attempt 1')
                % % Additional tweaks to make SOS Foton Filters without errors (may be inaccurate)
                try
                    quack3andahalf_modified(zpk(cplxpair(z,0.01),cplxpair(p,0.01),real(k)),4096,filename);
                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                catch
                    disp('Attempt 2')
                    try
                        quack3andahalf_modified(zpk(cplxpair(z,0.1),cplxpair(p,0.1),real(k)),4096,filename);
                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                    catch
                        disp('Attempt 3')
                        try
                            quack3andahalf_modified(zpk(cplxpair(z,0.3),cplxpair(p,0.3),real(k)),4096,filename);
                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                        catch
                            disp('Attempt 4')
                            try
                                quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                            catch
                                disp('Attempt 5')
                                try
                                    quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                catch
                                    disp(['Could not generate FOTON SOS Filters with N=',num2str(N),' Poles']);
                                end
                            end
                        end
                    end
                end
            end


            cd(Current_Dir)
            
            % Save Fig
            %saveas(Fig3,sprintf('%s/tfResult.png',Result_folder_name));
            %saveas(Fig3,sprintf('%s/tfResult.fig',Result_folder_name));
            
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            movefile(sprintf('%s.txt',filename),  sprintf('%s',filepath));
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',N));
            app.SOSFilterFOTONTextArea.Value = cwText;
            cd(Current_Dir);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            cd(Current_Dir)
            
        end

        % Callback function
        function RunButtonPushed(app, event)
            
            
            app.StatusField.Value = 'Busy';
            app.StatusField_5.Value = 'Busy';
            app.StatusField_6.Value = 'Busy';
            app.StatusLamp.Color = 'red';
            app.StatusLamp_5.Color = 'red';
            app.StatusLamp_6.Color = 'red';
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            % Main Result folder
            if ~exist('tf_ResultFolder','dir') == 7
                mkdir('tf_ResultFolder')
            end
            
            Result_folder_fullPath = sprintf('./tf_ResultFolder/%s',char(app.Result_folder_nameEditField.Value));
            
            Current_dir = pwd;
            try
                movefile('SOS_full_path.txt', './junk')
                movefile('Result_folder_fullPath.txt','./junk');
            catch
            end


            % prevent existing Result file overwrite
            if exist(Result_folder_fullPath,'dir')==7
                Result_folder_fullPath = sprintf('%s_%d',Result_folder_fullPath,randi(1e4));
            end
            
            mkdir(Result_folder_fullPath);
            cd(Result_folder_fullPath);
            Result_folder_name = pwd;
            SOS_folder_name = 'SOS_Results';
            mkdir(SOS_folder_name);
            cd(SOS_folder_name)
            SOS_full_path = pwd;
            
            cd(Current_dir)
            diary('SOS_full_path.txt');
            disp(SOS_full_path)
            diary('off')
            
            diary('Result_folder_fullPath.txt');
            disp(Result_folder_fullPath)
            diary('off')


            IDX = 1;
            Count = 1;


            for N = app.MinPolesEditField.Value:app.MaxPolesEditField.Value
                
                disp(['Estimating TF using ',num2str(N),' Poles...'])
                
                try
                    
                    
                    BO = bodeoptions;
                    BO.PhaseWrapping = 'on';
                    BO.FreqUnits = 'Hz';
                    
                    
                    FW = load('FilterWeights.mat'); FW = FW.FilterWeights;
                    FW = FW';
                    numel(FW);
                    
                    %%% Weighting Filter
                    if app.PassbandsHzButton.Value == 1
                        
                        
                        if numel(FW)     == 12
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6); FW(7),FW(8);FW(9),FW(10); FW(11),FW(12);];
                        elseif numel(FW) == 10
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6);FW(7),FW(8); FW(9),FW(10);];
                        elseif numel(FW)   == 8
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6); FW(7),FW(8);];
                        elseif numel(FW) == 6
                            WeightFilter = [FW(1),FW(2);FW(3),FW(4);FW(5),FW(6);];
                        elseif numel(FW) == 4
                            WeightFilter =  [FW(1),FW(2);FW(3),FW(4);];
                        elseif numel(FW) == 2
                            WeightFilter = [FW(1),FW(2);];
                        else
                            WeightFilter = [0.01,30;]*2*pi;
                        end
                        
                    elseif app.InverseButton.Value == 1
                        WeightFilter = 'inv';
                        
                    elseif app.InverseSquareRootButton.Value == 1
                        WeightFilter = 'invsqrt';
                        
                    end
                    
                    opts_set = tfestOptions('Weightingfilter',WeightFilter,...
                        'EnforceStability',true,...
                        'InitMethod',app.InitMethodEditField.Value,...
                        'SearchMethod',app.SearchMethodEditField.Value);
                    opts_set.SearchOption.MaxIter = app.MaxIterEditField.Value;
                    
                    G = tfest(sys,N,N,NaN,opts_set);
                    
                    
                    [G_mag,G_phs, G_W]  = bode(G,'BO');
                    G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
                    G_freq             = G_W;


                    [TF_resp,TF_W]  = frdata(sys,'v');
                    TF_freq             = TF_W;
                    TF_mag  = abs(TF_resp);
                    TF_phs  = angle(TF_resp);
                    
                    
                    if Count == 1
                        
                        loglog(app.UIAxes2_37,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_37,'on');
                        hold(app.UIAxes2_37,'on');
                        loglog(app.UIAxes2_37,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_37,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_37,'Frequency (Hz)')
                        ylabel(app.UIAxes2_37,'Mag')
                        legend(app.UIAxes2_37,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_37,'off');
                        
                        
                        semilogx(app.UIAxes2_38,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_38,'on');
                        hold(app.UIAxes2_38,'on');
                        semilogx(app.UIAxes2_38,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_38,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_38,'Frequency (Hz)')
                        ylabel(app.UIAxes2_38,'Phase (deg)')
                        hold(app.UIAxes2_38,'off');
                        
                        %figure(111)
                        %bode(sys,BO)
                        %hold on
                        %bode(G,BO)
                        %[z,p,k] = zpkdata(G);
                        %H = zpk(z,p,k);
                        %bode(H,BO)


                    elseif Count == 2
                        
                        loglog(app.UIAxes2_39,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_39,'on');
                        hold(app.UIAxes2_39,'on');
                        loglog(app.UIAxes2_39,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_39,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_39,'Frequency (Hz)')
                        ylabel(app.UIAxes2_39,'Mag')
                        legend(app.UIAxes2_39,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_39,'off');
                        
                        
                        semilogx(app.UIAxes2_40,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_40,'on');
                        hold(app.UIAxes2_40,'on');
                        semilogx(app.UIAxes2_40,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_40,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_40,'Frequency (Hz)')
                        ylabel(app.UIAxes2_40,'Phase (deg)')
                        hold(app.UIAxes2_40,'off');
                        
                    elseif Count == 3
                        
                        loglog(app.UIAxes2_41,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_41,'on');
                        hold(app.UIAxes2_41,'on');
                        loglog(app.UIAxes2_41,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_41,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_41,'Frequency (Hz)')
                        ylabel(app.UIAxes2_41,'Mag')
                        legend(app.UIAxes2_41,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_41,'off');
                        
                        
                        semilogx(app.UIAxes2_42,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_42,'on');
                        hold(app.UIAxes2_42,'on');
                        semilogx(app.UIAxes2_42,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_42,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_42,'Frequency (Hz)')
                        ylabel(app.UIAxes2_42,'Phase (deg)')
                        hold(app.UIAxes2_42,'off');
                        
                    elseif Count == 4
                        
                        loglog(app.UIAxes2_43,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_43,'on');
                        hold(app.UIAxes2_43,'on');
                        loglog(app.UIAxes2_43,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_43,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_43,'Frequency (Hz)')
                        ylabel(app.UIAxes2_43,'Mag')
                        legend(app.UIAxes2_43,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_43,'off');
                        
                        
                        semilogx(app.UIAxes2_44,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_44,'on');
                        hold(app.UIAxes2_44,'on');
                        semilogx(app.UIAxes2_44,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_44,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_44,'Frequency (Hz)')
                        ylabel(app.UIAxes2_44,'Phase (deg)')
                        hold(app.UIAxes2_44,'off');
                        
                    elseif Count == 5
                        
                        loglog(app.UIAxes2_45,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_45,'on');
                        hold(app.UIAxes2_45,'on');
                        loglog(app.UIAxes2_45,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_45,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_45,'Frequency (Hz)')
                        ylabel(app.UIAxes2_45,'Mag')
                        legend(app.UIAxes2_45,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_45,'off');
                        
                        
                        semilogx(app.UIAxes2_46,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_46,'on');
                        hold(app.UIAxes2_46,'on');
                        semilogx(app.UIAxes2_46,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_46,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_46,'Frequency (Hz)')
                        ylabel(app.UIAxes2_46,'Phase (deg)')
                        hold(app.UIAxes2_46,'off');
                        
                        
                    elseif Count == 6
                        
                        loglog(app.UIAxes2_47,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_47,'on');
                        hold(app.UIAxes2_47,'on');
                        loglog(app.UIAxes2_47,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_47,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_47,'Frequency (Hz)')
                        ylabel(app.UIAxes2_47,'Mag')
                        legend(app.UIAxes2_47,['Poles = ',num2str(N)],'Location','Best')
                        hold(app.UIAxes2_47,'off');
                        
                        
                        semilogx(app.UIAxes2_48,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
                        grid(app.UIAxes2_48,'on');
                        hold(app.UIAxes2_48,'on');
                        semilogx(app.UIAxes2_48,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
                        xlim(app.UIAxes2_48,[TF_freq(1) TF_freq(end)]);
                        xlabel(app.UIAxes2_48,'Frequency (Hz)')
                        ylabel(app.UIAxes2_48,'Phase (deg)')
                        hold(app.UIAxes2_48,'off');
                        
                        
                    end
                    
                    Count = Count + 1;
                    
                    
                    [z,p,k] = zpkdata(G,'v');
                    
                    % % Additional tweaks to make SOS Foton Filters without errors (may be inaccurate)
                    % k = real(k);
                    % [imagZID] = find(imag(z)==0);
                    % z(imagZID) = real(z(imagZID));
                    % [imagPID] = find(imag(p)==0);
                    % z(imagPID) = real(z(imagPID));
                    % z = round(z);
                    % p = round(p);
                    
                    
                    tfDATA(IDX).NPoles = N;
                    tfDATA(IDX).zpkDATA.z = z;
                    tfDATA(IDX).zpkDATA.p = p;
                    tfDATA(IDX).zpkDATA.k = k;
                    
                    filename = sprintf('SOS_NPoles_%d',N);
                    % quack3andahalf_modified(G,4096,filename);
                    %quack3andahalf(G,4096);
                    
                    try
                        
                        quack3andahalf_modified(zpk(cplxpair(z,0.001),cplxpair(p,0.001),real(k)),4096,filename);
                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                    catch
                        disp('Attempt 1')
                        % % Additional tweaks to make SOS Foton Filters without errors (may be inaccurate)
                        try
                            quack3andahalf_modified(zpk(cplxpair(z,0.01),cplxpair(p,0.01),real(k)),4096,filename);
                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                        catch
                            disp('Attempt 2')
                            try
                                quack3andahalf_modified(zpk(cplxpair(z,0.1),cplxpair(p,0.1),real(k)),4096,filename);
                                disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                            catch
                                disp('Attempt 3')
                                try
                                    quack3andahalf_modified(zpk(cplxpair(z,0.3),cplxpair(p,0.3),real(k)),4096,filename);
                                    disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                catch
                                    disp('Attempt 4')
                                    try
                                        quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                        disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                    catch
                                        disp('Attempt 5')
                                        try
                                            quack3andahalf_modified(zpk(cplxpair(z,0.5),cplxpair(p,0.5),real(k)),4096,filename);
                                            disp(['Generated FOTON SOS Filters with N=',num2str(N),' Poles']);
                                        catch
                                            disp(['Could not generate FOTON SOS Filters with N=',num2str(N),' Poles']);
                                        end
                                    end
                                end
                            end
                        end
                    end
                    movefile(sprintf('%s.txt',filename),  sprintf('%s',SOS_full_path));
                    
                    
                    IDX = IDX+1;
                    
                catch exception
                    msgTxt = getReport(exception)
                    IDX = IDX+1;
                    continue
                end
                
            end
            
            cd(Current_dir)
            
            % Save Fig
            %saveas(Fig3,sprintf('%s/tfResult.png',Result_folder_name));
            %saveas(Fig3,sprintf('%s/tfResult.fig',Result_folder_name));
            
            % Save TF Fit Dat
            save(sprintf('%s/tfDATA.mat',Result_folder_name),'tfDATA');
            
            disp(['Results saved to ',Result_folder_name])
            
            
            app.StatusField.Value = char(['Results saved to: ', Result_folder_name]);
            app.StatusField_5.Value = char(['Results saved to: ', Result_folder_name]);
            app.StatusField_6.Value = char(['Results saved to: ', Result_folder_name]);
            
            app.StatusLamp.Color='green';
            app.StatusLamp_5.Color='green';
            app.StatusLamp_6.Color='green';
            
            
            value = app.MinPolesEditField.Value;
            app.ResultsforNEditField.Value = value;
            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',value));
            app.SOSFilterFOTONTextArea.Value = cwText;
            cd(Current_Dir);
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            
            Nvalue = app.MaxPolesEditField.Value;
            app.ResultsforNEditField.Value = Nvalue;
            
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            G = zpk(zDATA,pDATA,kDATA);
            save('zpkFilter.mat','G');
            
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');
            
            
            cd(Current_Dir)
            
        end

        % Button pushed function: SavePlotButton
        function SavePlotButtonPushed(app, event)
            generate_plots(app.Slider_3.Value, app.Slider_4.Value,app.FigNameEditField.Value)
        end

        % Button pushed function: SavePlotButton_2
        function SavePlotButton_2Pushed(app, event)
            generate_plots(app.Slider.Value, app.Slider_2.Value,app.FigNameEditField.Value)
        end

        % Button pushed function: SavePlotButton_3
        function SavePlotButton_3Pushed(app, event)
            generate_plots_with_fits(app.Slider_5.Value, app.Slider_6.Value,app.FigNameEditField_2.Value)
        end

        % Callback function
        function SmoothButtonValueChanged(app, event)
            addpath('measured_TFs');
            TF_file = app.ChooseTFListBox.Value;
            
            app.ExtremeSmoothButton.Value = 0;


            if (app.FreqReal_TFImag_TFButton.Value) == 1
                x  = load(char(TF_file));
                y = x(:,2)+1i*x(:,3);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FreqComplex_TFButton.Value) == 1
                x  = load(char(TF_file));
                y  = x(:,2);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FRDModelButton.Value) == 1
                sys = load(char(TF_file));
                sys = sys.sys;
            end


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            
            if app.SmoothButton.Value == 1 && app.ExtremeSmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag  = smooth(TF_mag,'sgolay',1);
                TF_phs  = smooth(TF_phs,'sgolay',1);
                color_val =   [0.8500  ,  0.3250 ,   0.0980];
            elseif app.ExtremeSmoothButton.Value == 1 && app.SmoothButton.Value == 0
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                TF_mag =  csaps(TF_freq,TF_mag,0.99999,TF_freq);
                TF_phs =  csaps(TF_freq,TF_phs,0.99999,TF_freq);
                color_val = [    0.9290  ,  0.6940 ,   0.1250];
            else
                TF_mag  = abs(TF_resp);
                TF_phs  = angle(TF_resp);
                color_val = [  0  ,  0.4470  ,  0.7410];
            end
            
            TF_TF= TF_mag.*exp(1i*TF_phs);
            
            
            sys  = frd(TF_TF,TF_W);
            save('./measured_TFs/sys.mat','sys');
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';


            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')


            app.updateListFunc;
        end

        % Button pushed function: SmoothButton_2
        function SmoothButton_2Pushed(app, event)
            FMinVal = app.Slider.Value;
            FMaxVal = app.Slider_2.Value;
            
            addpath('measured_TFs');
            
            
            TF_file = app.ChooseTFListBox.Value;
            
            app.ExtremeSmoothButton.Value = 0;
            app.SmoothButton.Value = 0;
            
            
            if (app.FreqReal_TFImag_TFButton.Value) == 1
                x  = load(char(TF_file));
                y = x(:,2)+1i*x(:,3);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FreqComplex_TFButton.Value) == 1
                x  = load(char(TF_file));
                y  = x(:,2);
                f = x(:,1);
                sys = frd(y,f,'FrequencyUnit','Hz');
            elseif (app.FRDModelButton.Value) == 1
                sys = load(char(TF_file));
                sys = sys.sys;
            end


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            
            [~,FMinID] = min(abs(TF_freq - FMinVal));
            [~,FMaxID] = min(abs(FMaxVal - TF_freq));


            %             if app.SmoothButton.Value == 1 && app.ExtremeSmoothButton.Value == 0
            disp(1)
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            %TF_mag(FMinID:FMaxID)  = smooth(TF_mag(FMinID:FMaxID),'sgolay',app.EditField_2.Value);
            TF_mag(FMinID:FMaxID)  = smooth(TF_mag(FMinID:FMaxID),app.EditField_2.Value);
            TF_phs(FMinID:FMaxID)  = smooth(TF_phs(FMinID:FMaxID),app.EditField_2.Value);
            color_val =   [0.8500  ,  0.3250 ,   0.0980];
            %             elseif app.ExtremeSmoothButton.Value == 1 && app.SmoothButton.Value == 0
            %                 disp(2)
            %                 TF_mag  = abs(TF_resp);
            %                 TF_phs  = angle(TF_resp);
            %                 TF_mag(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_mag(FMinID:FMaxID),0.99999,TF_freq(FMinID:FMaxID));
            %                 TF_phs(FMinID:FMaxID) =  csaps(TF_freq(FMinID:FMaxID),TF_phs(FMinID:FMaxID),0.99999,TF_freq(FMinID:FMaxID));
            %                 color_val = [    0.9290  ,  0.6940 ,   0.1250];
            %             else
            %                 disp(3)
            %                 TF_mag  = abs(TF_resp);
            %                 TF_phs  = angle(TF_resp);
            %                 color_val = [  0  ,  0.4470  ,  0.7410];
            %             end
            ls
            TF_TF= TF_mag.*exp(1i*TF_phs);
            
            
            sys  = frd(TF_TF,TF_W);
            save('./measured_TFs/sys.mat','sys');
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';


            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')


            app.updateListFunc;
            
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            
            if app.ResultsforNEditField.Value <= app.MaxPolesEditField.Value - 1 
            
            app.ResultsforNEditField.Value = app.ResultsforNEditField.Value + 1 ;
            
            Nvalue = app.ResultsforNEditField.Value;
            N = app.ResultsforNEditField.Value;
            
            
            Current_Dir = pwd;
            filepath = strtrim(fileread('SOS_full_path.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            cwText = fileread(sprintf('SOS_NPoles_%d.txt',Nvalue));
            app.SOSFilterFOTONTextArea.Value = cwText;
            
            cd(Current_Dir);
            filepath = strtrim(fileread('Result_folder_fullPath.txt'));
            filepath(filepath==',') = [];
            cd(filepath);
            load('tfDATA.mat');
            
            for i = 1:numel(tfDATA)
                if tfDATA(i).NPoles == Nvalue
                    Index = i;
                end             
            end
            
            zDATA =  (tfDATA(Index).zpkDATA.z);
            clc
            cd(Current_Dir)
            filename  = 'zDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('zDATA.txt');
            format shortEng
            disp(zDATA)
            diary off           
            zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            app.ZeroTextArea.Value = zText{:};
            
            
            pDATA =  (tfDATA(Index).zpkDATA.p);
            clc
            cd(Current_Dir)
            filename  = 'pDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('pDATA.txt');
            format shortEng
            disp(pDATA)
            diary off           
            pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            app.PoleTextArea.Value = pText{:};
            
            
            kDATA =  (tfDATA(Index).zpkDATA.k);
            clc
            cd(Current_Dir)
            filename  = 'kDATA.txt';
            fID = fopen(filename,'w');
            fwrite(fID,sprintf('%s',char(' ')));
            fclose(fID);
            diary('kDATA.txt');
            disp(kDATA)
            diary off           
            kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            app.GainTextArea.Value = kText{:};
            
            
            sys = load(char(['./measured_TFs/sys.mat']));
            sys = sys.sys;
            
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';
            
            
            %zText = textscan(fopen('zDATA.txt'),'%s','Delimiter','\n');
            z = app.ZeroTextArea.Value;
            %z  = zText{:};
            if strcmp(z(end),{''})
                z(end) = '';
            end
            %z(cellfun(@isnan,z)) = [];
            %z(cellfun(@isinf,z)) = [];
            z = cellfun(@str2double,z);
            
            %pText = textscan(fopen('pDATA.txt'),'%s','Delimiter','\n');
            p = app.PoleTextArea.Value;
            %p  = pText{:};
            if strcmp(p(end),{''})
                p(end) = '';
            end
            %p(cellfun(@isnan,p)) = [];
            %p(cellfun(@isinf,p)) = [];
            p = cellfun(@str2double,p);
            
            %kText = textscan(fopen('kDATA.txt'),'%s','Delimiter','\n');
            k = app.GainTextArea.Value;
            %k  = kText{:};
            if strcmp(k(end),{''})
                k(end) = '';
            end
            %k(cellfun(@isnan,k)) = [];
            %k(cellfun(@isinf,k)) = [];
            k = cellfun(@str2double,k);
            
            disp(z)
            z(isnan(z))=[];
            p(isnan(p))=[];
            k(isnan(k))=[];
            
            G = zpk(z,p,k);
            save('zpkFilter.mat','G');
            
            [G_mag,G_phs, G_W]  = bode(G,'BO');
            G_mag = squeeze(G_mag); G_phs = squeeze(G_phs); G_W = squeeze(G_W);
            G_freq             = G_W;


            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            
            
            loglog(app.UIAxes2,TF_freq,TF_mag,'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes2,'on');
            hold(app.UIAxes2,'on');
            loglog(app.UIAxes2,G_freq,G_mag,'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes2,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes2,'Frequency (Hz)')
            ylabel(app.UIAxes2,'Mag')
            legend(app.UIAxes2,['Poles = ',num2str(N)],'Location','Best')
            hold(app.UIAxes2,'off');
            
            
            semilogx(app.UIAxes3,TF_freq,wrapTo180(TF_phs*180/pi),'color',[  0  ,  0.4470  ,  0.7410]);
            grid(app.UIAxes3,'on');
            hold(app.UIAxes3,'on');
            semilogx(app.UIAxes3,G_freq,wrapTo180(G_phs),'color',[0.8500  ,  0.3250 ,   0.0980]);
            xlim(app.UIAxes3,[TF_freq(1) TF_freq(end)]);
            xlabel(app.UIAxes3,'Frequency (Hz)')
            ylabel(app.UIAxes3,'Phase (deg)')
            hold(app.UIAxes3,'off');


            cd(Current_Dir);
            
            end
        end

        % Callback function
        function WeightingFilterEditFieldValueChanged(app, event)
            value = app.EditField.Value ;
            value = char(value);
            value(value == ' ') = [];
            value = strsplit(value,',');
            FilterWeights = zeros(numel(value),1);
            for i = 1:numel(value)
                FilterWeights(i) = str2double(value{i});
            end
            
            save('FilterWeights.mat','FilterWeights')     ;
            disp('New Filter Weights Saved...')
            disp(FilterWeights)
        end

        % Button pushed function: XlimFilterButton
        function XlimFilterButtonPushed(app, event)
            
            
            FMin = app.Slider_3.Value;
            FMax = app.Slider_4.Value;
            
            addpath('measured_TFs');
            
            
            TF_file = app.ChooseTFListBox.Value;


            sys = load(['./measured_TFs/sys.mat']);
            sys = sys.sys;
            
            
            [TF_resp,TF_W]  = frdata(sys,'v');
            TF_freq             = TF_W;
            
            TF_mag  = abs(TF_resp);
            TF_phs  = angle(TF_resp);
            
            color_val =   [0.8500  ,  0.3250 ,   0.0980];
            
            BO = bodeoptions;
            BO.PhaseWrapping = 'on';
            BO.FreqUnits = 'Hz';


            loglog(app.UIAxes,TF_freq,TF_mag,'color',color_val);
            grid(app.UIAxes,'on');
            xlim(app.UIAxes,[FMin FMax]);
            xlabel(app.UIAxes,'Frequency (Hz)')
            ylabel(app.UIAxes,'Mag')
            
            semilogx(app.UIAxes_2,TF_freq,wrapTo180(TF_phs*180/pi),'color',color_val);
            grid(app.UIAxes_2,'on');
            xlim(app.UIAxes_2,[FMin FMax]);
            xlabel(app.UIAxes_2,'Frequency (Hz)')
            ylabel(app.UIAxes_2,'Phase (deg)')


            app.updateListFunc;
        end

        % Button pushed function: XlimFilterButton_2
        function XlimFilterButton_2Pushed(app, event)
            FMin = app.Slider_5.Value;
            FMax = app.Slider_6.Value;
            
            xlim(app.UIAxes2,[FMin,FMax]);
            xlim(app.UIAxes3,[FMin,FMax]);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.SizeChangedFcn = createCallbackFcn(app, @InputTabSizeChanged, true);
            app.InputTab.Title = 'Input';
            app.ChooseTFListBoxLabel = uilabel(app.InputTab);
            app.ChooseTFListBoxLabel.Text = 'Choose TF ';
            app.ChooseTFListBox = uilistbox(app.InputTab);
            app.ChooseTFListBox.ValueChangedFcn = createCallbackFcn(app, @ChooseTFListBoxValueChanged, true);
            app.InputDataTypeButtonGroup = uibuttongroup(app.InputTab);
            app.InputDataTypeButtonGroup.Title = 'Input Data Type';
            app.FreqReal_TFImag_TFButton = uiradiobutton(app.InputDataTypeButtonGroup);
            app.FreqReal_TFImag_TFButton.Text = '[  Freq Real_TF Imag_TF  ]';
            app.FreqReal_TFImag_TFButton.Value = true;
            app.FreqComplex_TFButton = uiradiobutton(app.InputDataTypeButtonGroup);
            app.FreqComplex_TFButton.Text = '[ Freq Complex_TF]';
            app.FRDModelButton = uiradiobutton(app.InputDataTypeButtonGroup);
            app.FRDModelButton.Text = 'FRD Model';
            app.SmoothButton = uibutton(app.InputTab, 'state');
            app.SmoothButton.ValueChangedFcn = createCallbackFcn(app, @SmoothButtonValueChanged, true);
            app.SmoothButton.Text = 'Smooth';
            app.ExtremeSmoothButton = uibutton(app.InputTab, 'state');
            app.ExtremeSmoothButton.ValueChangedFcn = createCallbackFcn(app, @ExtremeSmoothButtonValueChanged, true);
            app.ExtremeSmoothButton.Text = 'Extreme Smooth';
            app.Result_folder_nameEditFieldLabel = uilabel(app.InputTab);
            app.Result_folder_nameEditFieldLabel.Text = 'Result_folder_name ';
            app.Result_folder_nameEditField = uieditfield(app.InputTab, 'text');
            app.Result_folder_nameEditField.Value = 'TF_Results';
            app.InitMethodLabel = uilabel(app.InputTab);
            app.InitMethodLabel.Text = 'Init Method';
            app.InitMethodEditField = uieditfield(app.InputTab, 'text');
            app.InitMethodEditField.Value = 'svf';
            app.SearchMethodLabel = uilabel(app.InputTab);
            app.SearchMethodLabel.Text = 'Search Method';
            app.SearchMethodEditField = uieditfield(app.InputTab, 'text');
            app.SearchMethodEditField.Value = 'lsqnonlin';
            app.MinPolesEditFieldLabel = uilabel(app.InputTab);
            app.MinPolesEditFieldLabel.Text = 'Min Poles';
            app.MinPolesEditField = uieditfield(app.InputTab, 'numeric');
            app.MinPolesEditField.ValueDisplayFormat = '%.0f';
            app.MinPolesEditField.Value = 6;
            app.MaxPolesLabel = uilabel(app.InputTab);
            app.MaxPolesLabel.Text = 'Max Poles';
            app.MaxPolesEditField = uieditfield(app.InputTab, 'numeric');
            app.MaxPolesEditField.ValueDisplayFormat = '%.0f';
            app.MaxPolesEditField.Value = 11;
            app.Optionsivsvfgpmfn4sidautoLabel = uilabel(app.InputTab);
            app.Optionsivsvfgpmfn4sidautoLabel.Text = 'Options: iv, svf, gpmf, n4sid, auto';
            app.OptionsgngnalmlsqnonlingradautoLabel = uilabel(app.InputTab);
            app.OptionsgngnalmlsqnonlingradautoLabel.Text = 'Options:  gn, gna, lm, lsqnonlin, grad, auto';
            app.StatusLampLabel = uilabel(app.InputTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.InputTab);
            app.StatusField = uieditfield(app.InputTab, 'text');
            app.TfestButton = uibutton(app.InputTab, 'push');
            app.TfestButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.TfestButton.Text = 'Tfest';
            app.MaxIterEditFieldLabel = uilabel(app.InputTab);
            app.MaxIterEditFieldLabel.Text = 'Max Iter';
            app.MaxIterEditField = uieditfield(app.InputTab, 'numeric');
            app.MaxIterEditField.ValueDisplayFormat = '%.0f';
            app.MaxIterEditField.Value = 100;
            app.UIAxes = uiaxes(app.InputTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.InputTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.ExFmin1Fmax1Fmin2Fmax2Fmin3Fmax3Label = uilabel(app.InputTab);
            app.ExFmin1Fmax1Fmin2Fmax2Fmin3Fmax3Label.Text = '(Ex: Fmin1,Fmax1,Fmin2,Fmax2,Fmin3,Fmax3)';
            app.FrequencyWeightingFilterButtonGroup = uibuttongroup(app.InputTab);
            app.FrequencyWeightingFilterButtonGroup.Title = 'Frequency Weighting Filter';
            app.PassbandsHzButton = uiradiobutton(app.FrequencyWeightingFilterButtonGroup);
            app.PassbandsHzButton.Text = 'Passbands (Hz)';
            app.PassbandsHzButton.Value = true;
            app.InverseButton = uiradiobutton(app.FrequencyWeightingFilterButtonGroup);
            app.InverseButton.Text = 'Inverse';
            app.InverseSquareRootButton = uiradiobutton(app.FrequencyWeightingFilterButtonGroup);
            app.InverseSquareRootButton.Text = 'Inverse SquareRoot';
            app.EditFieldLabel = uilabel(app.InputTab);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.InputTab, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @WeightingFilterEditFieldValueChanged, true);
            app.EditField.Value = '0.01,5';
            app.Label = uilabel(app.InputTab);
            app.Label.Text = '------->';
            app.SmoothButton_2 = uibutton(app.InputTab, 'push');
            app.SmoothButton_2.ButtonPushedFcn = createCallbackFcn(app, @SmoothButton_2Pushed, true);
            app.SmoothButton_2.Text = 'Smooth';
            app.Slider = uislider(app.InputTab);
            app.Slider.Limits = [0.005 10];
            app.Slider.Value = 2.5;
            app.Slider_2 = uislider(app.InputTab);
            app.Slider_2.Limits = [20 150];
            app.Slider_2.Value = 50;
            app.Slider_3 = uislider(app.InputTab);
            app.Slider_3.Limits = [0.005 10];
            app.Slider_3.MajorTickLabels = {'0.005', '', '', '', '10'};
            app.Slider_3.Value = 3;
            app.Slider_4 = uislider(app.InputTab);
            app.Slider_4.Limits = [20 150];
            app.Slider_4.Value = 25;
            app.XlimFilterButton = uibutton(app.InputTab, 'push');
            app.XlimFilterButton.ButtonPushedFcn = createCallbackFcn(app, @XlimFilterButtonPushed, true);
            app.XlimFilterButton.Text = 'Xlim Filter';
            app.ExtremeSmoothButton_2 = uibutton(app.InputTab, 'push');
            app.ExtremeSmoothButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtremeSmoothButton_2Pushed, true);
            app.ExtremeSmoothButton_2.Text = 'Extreme Smooth';
            app.EditField_2 = uieditfield(app.InputTab, 'numeric');
            app.EditField_2.Limits = [1 1000];
            app.EditField_2.ValueDisplayFormat = '%.0f';
            app.EditField_2.Value = 10;
            app.EditField_3 = uieditfield(app.InputTab, 'numeric');
            app.EditField_3.Limits = [0 1];
            app.EditField_3.ValueDisplayFormat = '%.7f';
            app.EditField_3.Value = 0.99999;
            app.SavePlotButton = uibutton(app.InputTab, 'push');
            app.SavePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButtonPushed, true);
            app.SavePlotButton.Text = 'Save Plot';
            app.SavePlotButton_2 = uibutton(app.InputTab, 'push');
            app.SavePlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButton_2Pushed, true);
            app.SavePlotButton_2.Text = 'Save Plot';
            app.FigNameEditFieldLabel = uilabel(app.InputTab);
            app.FigNameEditFieldLabel.Text = 'Fig Name';
            app.FigNameEditField = uieditfield(app.InputTab, 'text');
            app.FigNameEditField.Value = 'test';
            app.RescueButton = uibutton(app.InputTab, 'push');
            app.RescueButton.ButtonPushedFcn = createCallbackFcn(app, @RescueButtonPushed, true);
            app.RescueButton.Text = 'Rescue';
            app.RationalfitButton = uibutton(app.InputTab, 'push');
            app.RationalfitButton.ButtonPushedFcn = createCallbackFcn(app, @RationalfitButtonPushed, true);
            app.RationalfitButton.Text = 'Rationalfit';
            app.AddnewfileButton = uibutton(app.InputTab, 'push');
            app.AddnewfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewfileButtonPushed, true);
            app.AddnewfileButton.Text = 'Add new file';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.UIAxes2_37 = uiaxes(app.PlotsTab);
            app.UIAxes2_38 = uiaxes(app.PlotsTab);
            app.UIAxes2_39 = uiaxes(app.PlotsTab);
            app.UIAxes2_40 = uiaxes(app.PlotsTab);
            app.UIAxes2_41 = uiaxes(app.PlotsTab);
            app.UIAxes2_42 = uiaxes(app.PlotsTab);
            app.UIAxes2_43 = uiaxes(app.PlotsTab);
            app.UIAxes2_44 = uiaxes(app.PlotsTab);
            app.UIAxes2_45 = uiaxes(app.PlotsTab);
            app.UIAxes2_46 = uiaxes(app.PlotsTab);
            app.UIAxes2_47 = uiaxes(app.PlotsTab);
            app.UIAxes2_48 = uiaxes(app.PlotsTab);
            app.StatusLamp_5 = uilamp(app.PlotsTab);
            app.StatusField_5 = uieditfield(app.PlotsTab, 'text');
            app.RescueButton_2 = uibutton(app.PlotsTab, 'push');
            app.RescueButton_2.ButtonPushedFcn = createCallbackFcn(app, @RescueButton_2Pushed, true);
            app.RescueButton_2.Text = 'Rescue';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.SOSFilterFOTONTextAreaLabel = uilabel(app.FilterTab);
            app.SOSFilterFOTONTextAreaLabel.Text = 'SOS Filter (FOTON)';
            app.SOSFilterFOTONTextArea = uitextarea(app.FilterTab);
            app.ResultsforNEditFieldLabel = uilabel(app.FilterTab);
            app.ResultsforNEditFieldLabel.Text = 'Results for N =';
            app.ResultsforNEditField = uieditfield(app.FilterTab, 'numeric');
            app.ResultsforNEditField.ValueChangedFcn = createCallbackFcn(app, @ResultsforNEditFieldValueChanged, true);
            app.ResultsforNEditField.Value = 6;
            app.ResultsforNEditFieldLabel_2 = uilabel(app.FilterTab);
            app.ResultsforNEditFieldLabel_2.Text = 'Poles';
            app.StatusLampLabel_2 = uilabel(app.FilterTab);
            app.StatusLampLabel_2.Text = 'Status';
            app.StatusLamp_6 = uilamp(app.FilterTab);
            app.StatusField_6 = uieditfield(app.FilterTab, 'text');
            app.GetNewTFButton = uibutton(app.FilterTab, 'push');
            app.GetNewTFButton.ButtonPushedFcn = createCallbackFcn(app, @GetNewTFButtonPushed, true);
            app.GetNewTFButton.Text = 'Get New TF';
            app.ZeroTextAreaLabel = uilabel(app.FilterTab);
            app.ZeroTextAreaLabel.Text = 'Zero';
            app.ZeroTextArea = uitextarea(app.FilterTab);
            app.PoleTextAreaLabel = uilabel(app.FilterTab);
            app.PoleTextAreaLabel.Text = 'Pole';
            app.PoleTextArea = uitextarea(app.FilterTab);
            app.GainTextAreaLabel = uilabel(app.FilterTab);
            app.GainTextAreaLabel.Text = 'Gain';
            app.GainTextArea = uitextarea(app.FilterTab);
            app.RevertButton = uibutton(app.FilterTab, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.UIAxes2 = uiaxes(app.FilterTab);
            ylabel(app.UIAxes2, 'Mag')
            app.UIAxes3 = uiaxes(app.FilterTab);
            xlabel(app.UIAxes3, 'Frequency (Hz)')
            ylabel(app.UIAxes3, 'Phase (Deg)')
            app.TextArea = uitextarea(app.FilterTab);
            app.GetCommandLineOutputButton = uibutton(app.FilterTab, 'push');
            app.GetCommandLineOutputButton.ButtonPushedFcn = createCallbackFcn(app, @GetCommandLineOutputButtonPushed, true);
            app.GetCommandLineOutputButton.Text = 'Get Command Line Output';
            app.UpButton = uibutton(app.FilterTab, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.DownButton = uibutton(app.FilterTab, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.RescueButton_3 = uibutton(app.FilterTab, 'push');
            app.RescueButton_3.ButtonPushedFcn = createCallbackFcn(app, @RescueButton_3Pushed, true);
            app.RescueButton_3.Text = 'Rescue';
            app.Slider_5 = uislider(app.FilterTab);
            app.Slider_5.Limits = [0.005 10];
            app.Slider_5.MajorTickLabels = {'0.005', '2.005', '4.005', '6.005', '10'};
            app.Slider_5.Value = 3;
            app.Slider_6 = uislider(app.FilterTab);
            app.Slider_6.Limits = [20 150];
            app.Slider_6.Value = 25;
            app.XlimFilterButton_2 = uibutton(app.FilterTab, 'push');
            app.XlimFilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @XlimFilterButton_2Pushed, true);
            app.XlimFilterButton_2.Text = 'Xlim Filter';
            app.SavePlotButton_3 = uibutton(app.FilterTab, 'push');
            app.SavePlotButton_3.ButtonPushedFcn = createCallbackFcn(app, @SavePlotButton_3Pushed, true);
            app.SavePlotButton_3.Text = 'Save Plot';
            app.FigNameEditField_2Label = uilabel(app.FilterTab);
            app.FigNameEditField_2Label.Text = 'Fig Name';
            app.FigNameEditField_2 = uieditfield(app.FilterTab, 'text');
            app.FigNameEditField_2.Value = 'Fit_Result';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.TextArea2 = uitextarea(app.InfoTab);
            app.TextArea2.Value = {'TRANSFER FUNCTION ESTIMATION TOOLBOX'; ''; 'Developed by: Nikhil Mukund Menon '; '                        (nikhil@iucaa.in, nikhil.mukund@LIGO.ORG)'; ''; 'Based on MATLAB''s Rationalfit and Tfest functions'; '                 (Ref: https://in.mathworks.com/help/rf/ug/rationalfit.html)'; '                 (Ref: https://in.mathworks.com/help/ident/ref/tfest.html)'; ''; '## To Run App'; 'Open TFestimate_App_v1.mlapp'; 'Add new TF measurements by clicking the ''Add new file'' button'; 'Click either Rationalfit or Tfest       '; 'See & modify generated IIR filters in ''Filter'' Section'; 'Try modifying default parameters to obtain better fits'; ''; '::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::'; 'Add TF measurement data to measured_TFs subfolder.'; 'The following formats are accepted : '; '   1. [Freq real_TF imag_TF]'; '   2. [Freq complex_TF]'; '   3. FRD Model'; ''; ''; '::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::::'; '# Weighting Filter'; ''; 'Use either of ''Inverse'' or ''Inverse Square Root''  to capture low magnitude dynamics'; ''; ''; ''; ''};
            app.TFESTIMATELabel = uilabel(app.UIFigure);
            app.TFESTIMATELabel.Text = 'TF ESTIMATE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TFestimate_App_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end